classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Button_draw                  matlab.ui.control.Button
        DropDown_Choose              matlab.ui.control.DropDown
        HocLabel                     matlab.ui.control.Label
        HamsfxLabel                  matlab.ui.control.Label
        EditField_fx                 matlab.ui.control.EditField
        NhpbcnhayhEditFieldLabel     matlab.ui.control.Label
        EditField_h                  matlab.ui.control.NumericEditField
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        EditField_x                  matlab.ui.control.NumericEditField
        KtquaEditFieldLabel          matlab.ui.control.Label
        EditField_y                  matlab.ui.control.NumericEditField
        ChongiatrisaisDropDownLabel  matlab.ui.control.Label
        DropDown_Saiso               matlab.ui.control.DropDown
        NhpgiatriyLabel              matlab.ui.control.Label
        EditField_ya                 matlab.ui.control.EditField
        NhpgiatrixLabel              matlab.ui.control.Label
        EditField_xa                 matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_draw
        function Button_drawPushed(app, event)
            if strcmp(app.DropDown_Saiso.Value, 'O(h^2)')
                Oh = 2;
            else
                Oh = 1;
            end
            x = str2num(app.EditField_xa.Value);
            y = str2num(app.EditField_ya.Value);
            if (length(x) >= 3) && (length(y) >= 3)
                xi = app.EditField_x.Value;
                Choose = app.DropDown_Choose.Value;
                Saiso = app.DropDown_Saiso.Value;
                if Choose == "Xấp xỉ tiến"
                    y = taylor_xapxitien(x,y,xi,Oh);
                elseif Choose == "Xấp xỉ lùi"
                    y = taylor_xapxilui(x,y,xi,Oh);
                elseif Choose == "Xấp xỉ trung tâm"
                    y = taylor_xapxitrungtam(x,y,xi,Oh);
                else
                    error('Lua chon khong hop le');
                end
            else
                fx = app.EditField_fx.Value;
                h = app.EditField_h.Value;
                xi = app.EditField_x.Value;
                Choose = app.DropDown_Choose.Value;
                Saiso = app.DropDown_Saiso.Value;
                if Choose == "Xấp xỉ tiến"
                    y = taylor_fx_xapxitien(fx,xi,h,Oh);
                elseif Choose == "Xấp xỉ lùi"
                    y = taylor_fx_xapxilui(fx,xi,h,Oh);
                elseif Choose == "Xấp xỉ trung tâm"
                    y = taylor_fx_xapxitrungtam(fx,xi,h,Oh);
                end

            end
            app.EditField_y.Value = y;
        end

        % Value changed function: DropDown_Choose
        function DropDown_ChooseValueChanged(app, event)
            switch app.DropDown_Choose.Value
                case 'Xấp xỉ trung tâm'
                    app.DropDown_Saiso.Items = {'O(h^2)' 'O(h^4)'};
                otherwise
                    app.DropDown_Saiso.Items = {'O(h)' 'O(h^2)'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_draw = uibutton(app.UIFigure, 'push');
            app.Button_draw.ButtonPushedFcn = createCallbackFcn(app, @Button_drawPushed, true);
            app.Button_draw.Text = 'Tính đạo hàm';
            app.DropDown_Choose = uidropdown(app.UIFigure);
            app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DropDown_Choose.ValueChangedFcn = createCallbackFcn(app, @DropDown_ChooseValueChanged, true);
            app.DropDown_Choose.Value = 'Xấp xỉ tiến';
            app.HocLabel = uilabel(app.UIFigure);
            app.HocLabel.Text = 'Hoặc';
            app.HamsfxLabel = uilabel(app.UIFigure);
            app.HamsfxLabel.Text = 'Nhập hàm số f(x)';
            app.EditField_fx = uieditfield(app.UIFigure, 'text');
            app.EditField_fx.Value = '-0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25*x + 1.2';
            app.NhpbcnhayhEditFieldLabel = uilabel(app.UIFigure);
            app.NhpbcnhayhEditFieldLabel.Text = 'Nhập bước nhảy (h)';
            app.EditField_h = uieditfield(app.UIFigure, 'numeric');
            app.EditField_h.Value = 0.25;
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.EditField_x = uieditfield(app.UIFigure, 'numeric');
            app.EditField_x.Value = 0.5;
            app.KtquaEditFieldLabel = uilabel(app.UIFigure);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.EditField_y = uieditfield(app.UIFigure, 'numeric');
            app.EditField_y.Editable = 'off';
            app.ChongiatrisaisDropDownLabel = uilabel(app.UIFigure);
            app.ChongiatrisaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.DropDown_Saiso = uidropdown(app.UIFigure);
            app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
            app.DropDown_Saiso.Value = 'O(h^2)';
            app.NhpgiatriyLabel = uilabel(app.UIFigure);
            app.NhpgiatriyLabel.Text = 'Nhập giá trị y';
            app.EditField_ya = uieditfield(app.UIFigure, 'text');
            app.NhpgiatrixLabel = uilabel(app.UIFigure);
            app.NhpgiatrixLabel.Text = 'Nhập giá trị x';
            app.EditField_xa = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end