classdef TichPhan_DoAn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CHNTCHPHNDropDownLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        EditFieldx                      matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        EditFieldy                      matlab.ui.control.EditField
        HocLabel                        matlab.ui.control.Label
        NhphmsfxEditFieldLabel          matlab.ui.control.Label
        EditFieldfx                     matlab.ui.control.EditField
        NhpcntchphnEditFieldLabel       matlab.ui.control.Label
        EditFieldab                     matlab.ui.control.EditField
        KtqutchphndliuxyEditFieldLabel  matlab.ui.control.Label
        EditField_kqxy                  matlab.ui.control.NumericEditField
        KtqutchphnhmsfxEditFieldLabel   matlab.ui.control.Label
        EditField_kqfx                  matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        ButtonRun                       matlab.ui.control.StateButton
        NhpNLabel                       matlab.ui.control.Label
        EditFieldN                      matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ButtonRun
        function ButtonRunValueChanged(app, event)
            
            x1=app.EditFieldx.Value;
            x1=str2num(x1);
            y1=app.EditFieldy.Value;
            y1=str2num(y1);
            
            f=app.EditFieldfx.Value;
            a=app.EditFieldab.Value;
            a= str2num(a);
        
            N=app.EditFieldN.Value;
            N= str2num(N);

            if ~ isempty(f)

                f= str2func(['@(x)',f]);
            
            else 
                
                f= ''; 
            end

           switch app.DropDown.Value
               case 'Hình thang'
                   y=tichphanhinhthang(f,a(1),a(2),N);
                    case 'Simpson 1/3'
                   y=tichphansimpson13(f,a(1),a(2),N);
                    case 'Simpson 3/8'
                   y=tichphansimpson38(f,a(1),a(2),N);
           end
           switch app.DropDown.Value
               case 'Hình thang'
                   yp=tichphanhinhthang_xy(x1,y1,a(1),a(2));
                    case 'Simpson 1/3'
                   yp=tichphansimpson13_xy(x1,y1,a(1),a(2));
                    case 'Simpson 3/8'
                   yp=tichphansimpson38_xy(x1,y1,a(1),a(2));
           end
           app.EditField_kqfx.Value= y;
           app.EditField_kqxy.Value= yp;


        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            a=app.EditFieldab.Value;
            a= str2num(a);
            f=app.EditFieldfx.Value;
            

            if ~ isempty(f)

                f= str2func(['@(x)',f]);
            
            else 
                
                f= ''; 
            end
             if app.CheckBox.Value
               y1 = quad(f,a(1),a(2));
            app.CheckBox.Text = ['Kết quả tích phân chính xác của hàm f(x) : ' num2str(y1)]; 
           else 
               app.CheckBox.Text = 'Kết quả tích phân chính xác của hàm f(x) : ';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị tích phân ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CHNTCHPHNDropDownLabel = uilabel(app.UIFigure);
            app.CHNTCHPHNDropDownLabel.Text = 'CHỌN TÍCH PHÂN';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.DropDown.Value = 'Hình thang';
            app.NhpdliuxEditFieldLabel = uilabel(app.UIFigure);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.EditFieldx = uieditfield(app.UIFigure, 'text');
            app.EditFieldx.Value = '1 2 3 4';
            app.NhpdliuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.EditFieldy = uieditfield(app.UIFigure, 'text');
            app.EditFieldy.Value = '1 2 3 4';
            app.HocLabel = uilabel(app.UIFigure);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsfxEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmsfxEditFieldLabel.Text = 'Nhập hàm số f(x)';
            app.EditFieldfx = uieditfield(app.UIFigure, 'text');
            app.EditFieldfx.Value = '0.2+25*x-200*x.^2+675*x.^3-900*x.^4+400*x.^5;';
            app.NhpcntchphnEditFieldLabel = uilabel(app.UIFigure);
            app.NhpcntchphnEditFieldLabel.Text = 'Nhập cận tích phân';
            app.EditFieldab = uieditfield(app.UIFigure, 'text');
            app.EditFieldab.Value = '2 3';
            app.KtqutchphndliuxyEditFieldLabel = uilabel(app.UIFigure);
            app.KtqutchphndliuxyEditFieldLabel.Text = 'Kết quả tích phân dữ liệu x y';
            app.EditField_kqxy = uieditfield(app.UIFigure, 'numeric');
            app.KtqutchphnhmsfxEditFieldLabel = uilabel(app.UIFigure);
            app.KtqutchphnhmsfxEditFieldLabel.Text = 'Kết quả tích phân hàm số f(x) ';
            app.EditField_kqfx = uieditfield(app.UIFigure, 'numeric');
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Kết quả tích phân chính xác của hàm f(x) : ';
            app.ButtonRun = uibutton(app.UIFigure, 'state');
            app.ButtonRun.ValueChangedFcn = createCallbackFcn(app, @ButtonRunValueChanged, true);
            app.ButtonRun.Text = 'Thực Hiện';
            app.NhpNLabel = uilabel(app.UIFigure);
            app.NhpNLabel.Text = 'Nhập N';
            app.EditFieldN = uieditfield(app.UIFigure, 'text');
            app.EditFieldN.Value = '9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TichPhan_DoAn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end