classdef Project_MatlabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        GioiThieuNhom_Tab           matlab.ui.container.Tab
        TimNghiem_Tab               matlab.ui.container.Tab
        NoiSuy_Tab                  matlab.ui.container.Tab
        KQ                          matlab.ui.control.EditField
        KtquanisuyLabel             matlab.ui.control.Label
        GT                          matlab.ui.control.EditField
        NhpgiatricnnisuyLabel       matlab.ui.control.Label
        DaThuc                      matlab.ui.control.EditField
        KtquaathcnisuyLabel         matlab.ui.control.Label
        PhuongPhap_DropDown         matlab.ui.control.DropDown
        ChonphngphapnisuyLabel      matlab.ui.control.Label
        y                           matlab.ui.control.EditField
        NhpdliuyLabel               matlab.ui.control.Label
        x                           matlab.ui.control.EditField
        NhpdliuxLabel               matlab.ui.control.Label
        CanhBaoLabel                matlab.ui.control.Label
        RUNButton_2                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        HoiQuy_Tab                  matlab.ui.container.Tab
        predict_value               matlab.ui.control.EditField
        GitrcndonEditFieldLabel     matlab.ui.control.Label
        predict_result              matlab.ui.control.TextArea
        KtqudonTextAreaLabel        matlab.ui.control.Label
        regress_func                matlab.ui.control.TextArea
        PhngtrnhhiquyTextAreaLabel  matlab.ui.control.Label
        y_value                     matlab.ui.control.EditField
        GitryEditFieldLabel         matlab.ui.control.Label
        x_value                     matlab.ui.control.EditField
        GitrxEditFieldLabel         matlab.ui.control.Label
        regress_method              matlab.ui.control.ListBox
        PhngphpListBoxLabel         matlab.ui.control.Label
        Start                       matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        DaoHam_Tab                  matlab.ui.container.Tab
        KtquaaohamchinhxacCheckBox  matlab.ui.control.CheckBox
        SaiSo_DropDown              matlab.ui.control.DropDown
        ChongiatrisaisLabel         matlab.ui.control.Label
        PP_DropDown                 matlab.ui.control.DropDown
        PhngphapaohamLabel          matlab.ui.control.Label
        h_Label                     matlab.ui.control.Label
        j_Label                     matlab.ui.control.Label
        KQfxh_EditField             matlab.ui.control.EditField
        KQxy_EditField              matlab.ui.control.EditField
        RUNButton                   matlab.ui.control.Button
        e_Label                     matlab.ui.control.Label
        d_Label                     matlab.ui.control.Label
        c_Label                     matlab.ui.control.Label
        b_Label                     matlab.ui.control.Label
        a_Label                     matlab.ui.control.Label
        BuocNhay_EditField          matlab.ui.control.EditField
        Ham_EditField               matlab.ui.control.EditField
        GT_DH_EditField             matlab.ui.control.EditField
        Y_EditField                 matlab.ui.control.EditField
        X_EditField                 matlab.ui.control.EditField
        TichPhan_Tab                matlab.ui.container.Tab
        PPtp_DropDown               matlab.ui.control.DropDown
        ChonphngphapTichPhnDropDownLabel  matlab.ui.control.Label
        N                           matlab.ui.control.EditField
        Y_Label_tp                  matlab.ui.control.Label
        Kq_CXacCheckBox             matlab.ui.control.CheckBox
        Kq_fxLabel                  matlab.ui.control.Label
        Kq_xyLabel                  matlab.ui.control.Label
        HamLabel                    matlab.ui.control.Label
        Can_Label                   matlab.ui.control.Label
        Y_Label                     matlab.ui.control.Label
        X_Label                     matlab.ui.control.Label
        RUNButton_tp                matlab.ui.control.Button
        kq_fxEditField_tp           matlab.ui.control.EditField
        kq_xyEditField_tp           matlab.ui.control.EditField
        can_EditField               matlab.ui.control.EditField
        hamso_EditField             matlab.ui.control.EditField
        Y_EditField_2               matlab.ui.control.EditField
        X_EditField_2               matlab.ui.control.EditField
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    
    %Code Nội Suy_Đỗ Minh Chương_21207126
    methods (Access = private)
        function [yx, Pn] = NoiSuy_Lagrange(~, x1, y)
            x = sym('x');
            
            n = length(x1);
            Pn = sym(0);
            
            for i = 1:n
                term = y(i);
                for j = 1:n
                    if j ~= i
                        term = term * (x - x1(j)) / (x1(i) - x1(j));
                    end
                end
                Pn = Pn + term;
            end
            yx = matlabFunction(Pn);
            Pn = simplify(Pn);
            Pn = vpa(Pn, 4);
        end
        
        function [yx, Pn] = NoiSuy_Newton(~, x1, y)
            N = length(x1) - 1;
            DD = zeros(N + 1, N + 1);
            DD(1:N + 1, 1) = y';
            
            for k = 2:N + 1
                for m = 1:N + 2 - k
                    DD(m, k) = (DD(m + 1, k - 1) - DD(m, k - 1)) / (x1(m + k - 1) - x1(m));
                end
            end
            
            a = DD(1, :);
            n = a(N + 1);
            
            for k = N:-1:1
                n = [n a(k)] - [0 n * x1(k)];
            end
            
            syms x;
            Pn = a(N + 1);
            for k = N:-1:1
                Pn = Pn * (x - x1(k)) + a(k);
            end
            
            yx = matlabFunction(Pn);
            
            Pn = simplify(Pn);
            Pn = expand(Pn);
            Pn = vpa(Pn, 5);
        end
        
        
        function df = taylor_xapxitien(~, x, y, xi, Oh)
            if isempty(x) || isempty(y) || isempty(xi) || isempty(Oh)
                df = '';
                return;
            end
            h = x(2) - x(1);
            if xi < x(1) || xi >= x(end)
         
                df = 'l';
                return;
            end 
            i = find(x <= xi, 1, 'last');
            if i < length(x) && Oh == 1
                df = (y(i+1) - y(i)) / h;
            elseif i < length(x)-1 && Oh == 2
                df = (-y(i+2) + 4*y(i+1) - 3*y(i)) / (2*h);
            else
              
                return;
            end
        end
              
        
        function df = taylor_xapxilui(~, x, y, xi, Oh)
            if isempty(x) || isempty(y) || isempty(xi) || isempty(Oh)
                df = '';
                return;
            end
            h = x(2) - x(1);   
            if xi <= x(1) || xi > x(end)
                
                df = 'l';
                return;
            end
            i = find(x >= xi, 1, 'first');          
            if i > 1 && Oh == 1
                df = (y(i) - y(i-1)) / h;
            elseif i > 2 && Oh == 2
                df = (y(i-2) - 4*y(i-1) + 3*y(i)) / (2*h);
            else
                
                return;
            end
        end
        
        
        function df = taylor_xapxitrungtam(~, x, y, xi, Oh)
            if isempty(x) || isempty(y) || isempty(xi) || isempty(Oh)
                df = '';
                return;
            end
            h = x(2) - x(1);           
            if xi < x(3) || xi > x(end)          
                df = 'l';
                return;
            end           
            i = find(x <= xi, 1, 'last');
            k = find(x >= xi, 1, 'first');            
            if i < length(x) && k > 1 && Oh == 2
                df = (y(i+1) - y(i-1)) / (2 * h);
            elseif i < length(x)-1 && k > 2 && Oh == 1
                df = (-y(i+2) + 8*y(i+1) - 8*y(i-1) + y(i-2)) / (12*h);
            else
               
                df = 'l';
                return;
            end
        end        
        
        
        function df = taylor_fx_xapxitien(~, f, xi, h, Oh)
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 1
                df = (fx(xi + h) - fx(xi)) /  h;
            elseif Oh == 2
                df = (-fx(xi + 2*h) + 4*fx(xi + h) - 3*fx(xi)) / (2 * h);
            end
        end

        
        function df = taylor_fx_xapxilui(~, f, xi, h, Oh)
            
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 1
                df = (fx(xi) - fx(xi - h)) / h;
            elseif Oh == 2
                df = (fx(xi - 2*h) - 4*fx(xi - h) + 3*fx(xi)) / (2 * h);
            end
        end
        
        
        function df = taylor_fx_xapxitrungtam(~, f, xi, h, Oh)
            
            yx = str2sym(f);
            fx = matlabFunction(yx);
            
            if Oh == 2
                df = (fx(xi + h) - fx(xi - h)) / (2 * h);
            elseif Oh == 1
                df = (-fx(xi + 2*h) + 8*fx(xi + h) - 8*fx(xi - h) + fx(xi - 2*h)) / (12 * h);
            end
        end
        
        function drawing(app, y_fit, y_predict, print)
            [x, y] = InputData(app);
            plot(app.UIAxes_2, x, y , 'ro', x, y_fit, 'b-', 'LineWidth', 1);
            legend(app.UIAxes_2, 'Dữ liệu thực', 'Hàm hồi quy');
            y_predict1 = num2str(y_predict);
            app.predict_result.Value = y_predict1;
            app.regress_func.Value = print;
        end

        function [x, y] = InputData(app)
            x_input = app.x_value.Value;
            x_array = strsplit(x_input, ' ');
            x = str2double(x_array);
            y_input = app.y_value.Value;
            y_array = strsplit(y_input, ' ');
            y = str2double(y_array);
        end

        function [a0, a1, y_predict] = Linear_Regression1(~, x, y, x_predict)
            n = length(x);
            sumxy = 0;
            sumx = 0;
            sumy = 0;
            sumx2 = 0;
            st = 0;
            sr = 0;
            r2 = 0;
            for i = 1:1:n
                sumxy = sumxy + x(i)*y(i);
                sumx = sumx + x(i);
                sumy = sumy + y(i);
                sumx2 = sumx2 + x(i)^2;
            end
            a1 = (n * sumxy - sumx * sumy)/(n * sumx2 - (sumx)^2);
            a0 = mean(y) - a1 * mean(x);
            y_predict = a1 * x_predict + a0;
        end
        
        function [A0, A1, y_predict] = Non_Linear_Exp1(~, x, y, x_predict)
            Y = log(y);
            X = [ones(length(x), 1), x']
            coefficients = X\Y'
            A0 = coefficients(1);
            A1 = coefficients(2);
            y_predict = exp(A0 + A1 * x_predict);
        end
        
        function [A0, A1, y_predict] = Non_Linear_Loga1(~, x, y, x_predict)
            Y = log10(y);
            X = [ones(length(x), 1), log10(x)'];
            coefficients = X\Y';
            A0 = coefficients(1);
            A1 = coefficients(2);
            y_predict = 10^(A0)*x_predict^(A1);
        end
        
        
        function Linear_Regession(app)
            [x, y] = InputData(app);
            x_predict_inp = app.predict_value.Value;
            x_predict = str2double(x_predict_inp);
            [a0, a1, y_predict] = Linear_Regression1(app, x, y, x_predict);
            y_fit = a0 + a1 * x; % Compute corresponding y values
            print = ['y = ' num2str(a1) 'x + ' num2str(a0)];
            drawing(app, y_fit, y_predict, print);
        end
        
        function Non_Linear_Exp(app)
            [x, y] = InputData(app);
            x_predict_inp = app.predict_value.Value;
            x_predict = str2double(x_predict_inp);
            [A0, A1, y_predict] = Non_Linear_Exp1(app, x, y, x_predict);
            y_fit = exp(A0 + A1 * x);
            print = ['y = ' num2str(exp(A0)) 'e^(' num2str(A1) 'x)'];
            drawing(app, y_fit, y_predict, print);
        end
        
        function Non_Linear_Loga(app)
            [x, y] = InputData(app);
            x_predict_inp = app.predict_value.Value;
            x_predict = str2double(x_predict_inp);
            [A0, A1, y_predict] = Non_Linear_Loga1(app, x, y, x_predict);
            y_fit = 10^(A0)*x.^(A1);
            print = ['y = ' num2str(exp(A0)) 'x^(' num2str(A1) ')'];
            drawing(app, y_fit, y_predict, print);
        end
        
        function y = TichPhanHinhThang(~, fx, a, b, N)
            if isempty(a) || isempty(b) || isempty(fx) || a>b
                y = '';
                return;
            end          
            h = (b - a) / N;                      
            fa = fx(a);
            fb = fx(b);                      
            sum_inside = 0;
            for i = 1:N-1
                xi = a + i * h;
                sum_inside = sum_inside + fx(xi);
            end 
            y = (h / 2) * (fa + 2 * sum_inside + fb);
        end

        
        function result = TichPhanHinhThang_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end          
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end            
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');            
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end           
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);           
            N_selected = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N_selected;           
            fa = y_selected(1);
            fb = y_selected(end);           
            sum_inside = sum(y_selected(2:end-1));            
            result = (h / 2) * (fa + 2 * sum_inside + fb);
        end

        
        function y = TichPhanSimpson13(~, fx, a, b, N)
            if a > b || isempty(a) || isempty(b) || isempty(fx)
                y = '';
                return;
            end
            h = (b - a) / N;
            fa = fx(a);
            fb = fx(b);
            sum_odd = 0;
            sum_even = 0;           
            for i = 1:N-1
                xi = a + i * h;
                if mod(i, 2) == 1
                    sum_odd = sum_odd + fx(xi);
                else
                    sum_even = sum_even + fx(xi);
                end
            end
            y = (h / 3) * (fa + 4 * sum_odd + 2 * sum_even + fb);
        end

        
        function result = TichPhanSimpson13_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);            
            N = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N;            
            fa = y_selected(1);
            fb = y_selected(end);            
            sum_odd = sum(y_selected(2:2:end-1));
            sum_even = sum(y_selected(3:2:end-2));           
            result = (h / 3) * (fa + 4 * sum_odd + 2 * sum_even + fb);
        end

        
        function y = TichPhanSimpson38(~, fx, a, b, N)
            if isempty(a) || isempty(b) || isempty(fx) || a>b
                y = '';
                return;
            end
            h = (b - a) / N;
            fa = fx(a);
            fb = fx(b);
            sum_1 = 0;
            sum_2 = 0;
            sum_3 = 0;            
            for i = 1:N-1
                xi = a + i * h;
                if mod(i, 3) == 1
                    sum_1 = sum_1 + fx(xi);
                elseif mod(i, 3) == 2
                    sum_2 = sum_2 + fx(xi);
                else
                    sum_3 = sum_3 + fx(xi);
                end
            end
            y = (3 * h / 8) * (fa + 3 * sum_1 + 3 * sum_2 + 2 * sum_3 + fb);
        end

        
        function result = TichPhanSimpson38_xy(~, x, y, a, b)
            if isempty(a) || isempty(b) || isempty(x) || isempty(y) || length(x) ~= length(y)
                result = '';
                return;
            end
            if a < x(1) || b > x(end) || a>b
                result = 'Error';
                return;
            end           
            index_a = find(x >= a, 1);
            index_b = find(x <= b, 1, 'last');            
            if isempty(index_a) || isempty(index_b) || index_a > index_b
                result = 'Error';
                return;
            end            
            x_selected = x(index_a:index_b);
            y_selected = y(index_a:index_b);            
            N = length(x_selected) - 1;
            h = (x_selected(end) - x_selected(1)) / N;           
            fa = y_selected(1);
            fb = y_selected(end);          
            sum_1 = sum(y_selected(2:3:end-1));
            sum_2 = sum(y_selected(3:3:end-2));
            sum_3 = sum(y_selected(4:3:end-3));           
            result = (3 * h / 8) * (fa + 3 * sum_1 + 3 * sum_2 + 2 * sum_3 + fb);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton_2
        function Run_NoiSuy(app, event)
            x1 = app.x.Value;
            x1 = str2num(x1);
            y1 = app.y.Value;
            y1 = str2num(y1);
            
            if (size(x1) == size(y1))
                app.CanhBaoLabel.Visible = 'off';
                switch app.PhuongPhap_DropDown.Value
                    case 'Nội Suy Lagrange'
                        [yx, Pn] = NoiSuy_Lagrange(app, x1, y1);
                    case 'Nội Suy Newton'
                        [yx, Pn] = NoiSuy_Newton(app, x1, y1);
                end
                
                ns = app.GT.Value;
                ns = str2num(ns);
                if isempty(ns)
                    app.KQ.Value = '';
                    app.KQ.BackgroundColor = [1 1 1];          
                else
                    app.KQ.Value = num2str(yx(ns));
                    app.KQ.BackgroundColor = [0.06 1 1];
                end
                
                app.DaThuc.Value = char(Pn);
                
                cla(app.UIAxes);
                t = linspace(min(x1), max(x1), 100000);
                Pn_values = yx(t);
                
                switch app.PhuongPhap_DropDown.Value
                    case 'Nội Suy Lagrange'
                        plot(app.UIAxes, t, Pn_values, 'LineWidth', 2);
                    case 'Nội Suy Newton'
                        plot(app.UIAxes, t, Pn_values, 'k', 'LineWidth', 2);
                end
                
                
                hold(app.UIAxes, 'on');
                
                scatter(app.UIAxes, x1, y1, 50, 'red', 'filled');
                
            else
                app.KQ.Value = '';
                app.DaThuc.Value = '';
                cla(app.UIAxes);
                app.CanhBaoLabel.Visible = 'on';
            end
        end

        % Button pushed function: RUNButton
        function Run_DaoHam(app, event)

            x1 = str2num(app.X_EditField.Value);          
            y1 = str2num(app.Y_EditField.Value);
            
            f = app.Ham_EditField.Value;
            fx = str2func(['@(x)', f]);
            h = str2num(app.BuocNhay_EditField.Value);
 
            if strcmp(app.SaiSo_DropDown.Value, 'O(h^2)')
                Oh = 2;
            else
                Oh = 1;
            end
            
            xi = app.GT_DH_EditField.Value;
            xi = str2num(xi);
       
            if isempty(x1) || isempty(y1) || isempty(xi) 
                df = '';               
            end
             if isempty(f) || isempty(h) || isempty(xi)
                dfx = '';               
            end
            
            if strcmp(app.PP_DropDown.Value, 'Xấp Xỉ Tiến')
                df = taylor_xapxitien(app, x1, y1, xi, Oh);
                dfx = taylor_fx_xapxitien(app, f, xi, h, Oh);
            elseif strcmp(app.PP_DropDown.Value, 'Xấp Xỉ Lùi')
                df = taylor_xapxilui(app, x1, y1, xi, Oh);
                dfx = taylor_fx_xapxilui(app, f, xi, h, Oh);
            elseif strcmp(app.PP_DropDown.Value, 'Xấp Xỉ Trung Tâm')
                df = taylor_xapxitrungtam(app, x1, y1, xi, Oh);
                dfx = taylor_fx_xapxitrungtam(app, f, xi, h, Oh);
            end
            
            if ~isempty(dfx)                              
                app.KQfxh_EditField.Value = num2str(dfx);
                app.KQfxh_EditField.BackgroundColor = [1 1 0.07];
            else
                app.KQfxh_EditField.Value = '';
                app.KQfxh_EditField.BackgroundColor = [1 1 1];
            end
            if ~isempty(df)   
                app.KQxy_EditField.Value = num2str(df);
                app.KQxy_EditField.BackgroundColor = [0.06 1 1];
            else
                app.KQxy_EditField.Value = '';
                app.KQxy_EditField.BackgroundColor = [1 1 1];
            end


        end

        % Value changed function: PP_DropDown
        function DropDown_DaoHam(app, event)
             switch app.PP_DropDown.Value
                case 'Xấp Xỉ Trung Tâm'
                    app.SaiSo_DropDown.Items = {'O(h^2)' 'O(h^4)'};
                otherwise
                    app.SaiSo_DropDown.Items = {'O(h)' 'O(h^2)'};
             end       
        end

        % Value changed function: KtquaaohamchinhxacCheckBox
        function CheckBox_DaoHam(app, event)
            if app.KtquaaohamchinhxacCheckBox.Value
                    f = app.Ham_EditField.Value;
                    xi_str = app.GT_DH_EditField.Value;
                    xi = str2num(xi_str); % Chuyển đổi thành số
                    fx = str2sym(f);
                    syms x;
                    dfx = diff(fx, x);
                    dfx_func = matlabFunction(dfx);
                    dfxi = dfx_func(xi);
                    app.KtquaaohamchinhxacCheckBox.Text = ['Kết quả đạo hàm chính xác: ' num2str(dfxi)];
                else
                    app.KtquaaohamchinhxacCheckBox.Text = 'Kết quả đạo hàm chính xác:';
            end
        end

        % Button pushed function: Start
        function Run_HoiQuy(app, event)
            if app.regress_method.Value == 1
                Linear_Regession(app);
            elseif app.regress_method.Value == 2
                Non_Linear_Exp(app);
            elseif app.regress_method.Value == 3
                Non_Linear_Loga(app);
            end
            
            n = app.predict_value.Value;
            if ~isempty(n)                  
                app.predict_result.BackgroundColor = [0.06 1 1];
            else                
                app.predict_result.BackgroundColor = [1 1 1];
            end
             
        end

        % Button pushed function: RUNButton_tp
        function Run_TichPhan(app, event)
            x1 = app.X_EditField_2.Value;
            x1 = str2num(x1);
            y1 = app.Y_EditField_2.Value;
            y1 = str2num(y1);
            N1 = str2num(app.N.Value);
            a = str2num(app.can_EditField.Value);
            
            f = app.hamso_EditField.Value;
            if ~isempty(f)
                fx = str2func(['@(x)', f]);
            else
                fx = '';
            end
            
            switch app.PPtp_DropDown.Value
                case 'Hình Thang'
                    y2 = TichPhanHinhThang(app, fx, a(1), a(2), N1);
                    result = TichPhanHinhThang_xy(app,x1, y1, a(1), a(2));
                case 'Simpson 1/3'
                    if mod(N1, 2) == 1
                        N1 = N1 + 1;
                    end
                    y2 = TichPhanSimpson13(app, fx, a(1), a(2), N1);
                    result = TichPhanSimpson13_xy(app, x1, y1, a(1), a(2));
                case 'Simpson 3/8'
                    if mod(N1, 3) == 1
                        N1 = N1 + 2;
                    elseif mod(N1, 3) == 2
                        N1 = N1 + 1;
                    end
                    y2 = TichPhanSimpson38(app, fx, a(1), a(2), N1);
                    result = TichPhanSimpson38_xy(app, x1, y1, a(1), a(2));
            end
            
            app.kq_fxEditField_tp.Value = num2str(y2);
            app.kq_xyEditField_tp.Value = num2str(result);
            
            if ~isempty(f) && a(1) < a(2)
                % Vẽ đồ thị
                x_vals = linspace(a(1), a(2), 10000);
                u = linspace(a(1)-100, a(2)+100, 10000);
                y_vals = fx(x_vals);
                f = fx(u);
                
                % Tạo hoặc cập nhật đồ thị trong App Designer
                figure(app.UIFigure);
                plot(app.UIAxes_3, u, f, 'LineWidth', 2, 'Color', 'r');
                
                hold(app.UIAxes_3, 'on');
                
                % Tô đậm phần diện tích giữa hàm và trục Ox trên toàn bộ khoảng
                fill(app.UIAxes_3, [a(1), x_vals, a(2)], [0, y_vals, 0], 'k', 'FaceAlpha', 0.1);
                scatter(app.UIAxes_3, 0, 0, 20, 'k', 'filled');
                plot(app.UIAxes_3, [0,0], [-1000,1000], 'k', 'LineWidth', 0.5);
                plot(app.UIAxes_3, [a(1)-100, a(2)+100], [0,0], 'k', 'LineWidth', 0.5);
                
                
                % Đặt giới hạn cho trục x và trục y
                if a(1) >= 0
                    k1 = -2;
                else
                    k1 = a(1) - 1;
                end
                
                if a(2) <= 0
                    k2 = 2;
                else
                    k2 = a(2) + 1;
                end
                
                if min(y_vals) >= 0
                    k3 = -2;
                else
                    k3 = min(y_vals)-4;
                end
                
                if max(y_vals) <= 0
                    k4 = 2;
                else
                    k4 = max(y_vals)+4;
                end
                
                axis(app.UIAxes_3, [k1 k2 k3 k4]);
                
                hold(app.UIAxes_3, 'off');
                
            else
                app.UIAxes_3.cla;
                app.UIAxes_3.reset;
            end
        end

        % Value changed function: Kq_CXacCheckBox
        function Kq_CXacCheckBoxValueChanged(app, event)
            f = app.hamso_EditField.Value;
            if ~isempty(f)
                fx = str2func(['@(x)', f]);
            else
                fx = '';
            end
            a = str2num(app.can_EditField.Value);
            
            if app.Kq_CXacCheckBox.Value
                n1 = quad(f, a(1), a(2));
                app.Kq_CXacCheckBox.Text = ['Kết quả tích phân chính xác của hàm f(x): ' num2str(n1)];
            else
                app.Kq_CXacCheckBox.Text = 'Kết quả tích phân chính xác của hàm f(x): ';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GioiThieuNhom_Tab = uitab(app.TabGroup);
            app.GioiThieuNhom_Tab.Title = 'Giới thiệu nhóm';
            app.TimNghiem_Tab = uitab(app.TabGroup);
            app.TimNghiem_Tab.Title = 'Tìm nghiệm';
            app.NoiSuy_Tab = uitab(app.TabGroup);
            app.NoiSuy_Tab.Title = 'Nội suy';
            app.UIAxes = uiaxes(app.NoiSuy_Tab);
            title(app.UIAxes, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.RUNButton_2 = uibutton(app.NoiSuy_Tab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @Run_NoiSuy, true);
            app.RUNButton_2.Text = 'RUN';
            app.CanhBaoLabel = uilabel(app.NoiSuy_Tab);
            app.CanhBaoLabel.Visible = 'off';
            app.CanhBaoLabel.Text = 'Dữ Liệu Không Hợp Lệ';
            app.NhpdliuxLabel = uilabel(app.NoiSuy_Tab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.x = uieditfield(app.NoiSuy_Tab, 'text');
            app.x.Value = '1.4 1.5 1.6 1.7 1.8 1.9 2';
            app.NhpdliuyLabel = uilabel(app.NoiSuy_Tab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.y = uieditfield(app.NoiSuy_Tab, 'text');
            app.y.Value = '0.9523 0.9661 0.9763 0.9838 0.9891 0.9928 0.9953';
            app.ChonphngphapnisuyLabel = uilabel(app.NoiSuy_Tab);
            app.ChonphngphapnisuyLabel.Text = 'Chọn phương pháp nội suy';
            app.PhuongPhap_DropDown = uidropdown(app.NoiSuy_Tab);
            app.PhuongPhap_DropDown.Items = {'Nội Suy Lagrange', 'Nội Suy Newton'};
            app.PhuongPhap_DropDown.Value = 'Nội Suy Lagrange';
            app.KtquaathcnisuyLabel = uilabel(app.NoiSuy_Tab);
            app.KtquaathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.DaThuc = uieditfield(app.NoiSuy_Tab, 'text');
            app.NhpgiatricnnisuyLabel = uilabel(app.NoiSuy_Tab);
            app.NhpgiatricnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.GT = uieditfield(app.NoiSuy_Tab, 'text');
            app.GT.Value = '1.43';
            app.KtquanisuyLabel = uilabel(app.NoiSuy_Tab);
            app.KtquanisuyLabel.Text = 'Kết quả nội suy';
            app.KQ = uieditfield(app.NoiSuy_Tab, 'text');
            app.KQ.Editable = 'off';
            app.HoiQuy_Tab = uitab(app.TabGroup);
            app.HoiQuy_Tab.Title = 'Hồi quy';
            app.UIAxes_2 = uiaxes(app.HoiQuy_Tab);
            title(app.UIAxes_2, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Start = uibutton(app.HoiQuy_Tab, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Run_HoiQuy, true);
            app.Start.Text = 'RUN';
            app.PhngphpListBoxLabel = uilabel(app.HoiQuy_Tab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.regress_method = uilistbox(app.HoiQuy_Tab);
            app.regress_method.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.regress_method.ItemsData = [1 2 3];
            app.regress_method.Value = 1;
            app.GitrxEditFieldLabel = uilabel(app.HoiQuy_Tab);
            app.GitrxEditFieldLabel.Text = 'Giá trị x';
            app.x_value = uieditfield(app.HoiQuy_Tab, 'text');
            app.x_value.Value = '1 2 3 4 5 6 7';
            app.GitryEditFieldLabel = uilabel(app.HoiQuy_Tab);
            app.GitryEditFieldLabel.Text = 'Giá trị y';
            app.y_value = uieditfield(app.HoiQuy_Tab, 'text');
            app.y_value.Value = '2 5 3 4 5 6 7';
            app.PhngtrnhhiquyTextAreaLabel = uilabel(app.HoiQuy_Tab);
            app.PhngtrnhhiquyTextAreaLabel.Text = 'Phương trình hồi quy';
            app.regress_func = uitextarea(app.HoiQuy_Tab);
            app.regress_func.Editable = 'off';
            app.KtqudonTextAreaLabel = uilabel(app.HoiQuy_Tab);
            app.KtqudonTextAreaLabel.Text = 'Kết quả dự đoán';
            app.predict_result = uitextarea(app.HoiQuy_Tab);
            app.predict_result.Editable = 'off';
            app.GitrcndonEditFieldLabel = uilabel(app.HoiQuy_Tab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.predict_value = uieditfield(app.HoiQuy_Tab, 'text');
            app.predict_value.Value = '2.5';
            app.DaoHam_Tab = uitab(app.TabGroup);
            app.DaoHam_Tab.Title = 'Đạo hàm';
            app.X_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.X_EditField.Value = '0 0.25 0.5 0.75 1';
            app.Y_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.Y_EditField.Value = '1.2 1.1035 0.925 0.6363 0.2';
            app.GT_DH_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.GT_DH_EditField.Value = '0.5';
            app.Ham_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.Ham_EditField.Value = '-0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25*x + 1.2';
            app.BuocNhay_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.BuocNhay_EditField.Value = '0.25';
            app.a_Label = uilabel(app.DaoHam_Tab);
            app.a_Label.Text = 'Nhập dữ liệu x';
            app.b_Label = uilabel(app.DaoHam_Tab);
            app.b_Label.Text = 'Nhập dữ liệu y';
            app.c_Label = uilabel(app.DaoHam_Tab);
            app.c_Label.Text = 'Nhập hàm số f(x)';
            app.d_Label = uilabel(app.DaoHam_Tab);
            app.d_Label.Text = 'Nhập bước nhảy h';
            app.e_Label = uilabel(app.DaoHam_Tab);
            app.e_Label.Text = 'Giá trị cần tính đạo hàm';
            app.RUNButton = uibutton(app.DaoHam_Tab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @Run_DaoHam, true);
            app.RUNButton.Text = 'RUN';
            app.KQxy_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.KQxy_EditField.Editable = 'off';
            app.KQfxh_EditField = uieditfield(app.DaoHam_Tab, 'text');
            app.KQfxh_EditField.Editable = 'off';
            app.j_Label = uilabel(app.DaoHam_Tab);
            app.j_Label.Text = 'Kết quả đạo hàm theo dữ liệu x, y';
            app.h_Label = uilabel(app.DaoHam_Tab);
            app.h_Label.Text = 'Kết quả đạo hàm theo hàm f(x)';
            app.PhngphapaohamLabel = uilabel(app.DaoHam_Tab);
            app.PhngphapaohamLabel.Text = 'Phương pháp đạo hàm';
            app.PP_DropDown = uidropdown(app.DaoHam_Tab);
            app.PP_DropDown.Items = {'Xấp Xỉ Tiến', 'Xấp Xỉ Lùi', 'Xấp Xỉ Trung Tâm'};
            app.PP_DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDown_DaoHam, true);
            app.PP_DropDown.Value = 'Xấp Xỉ Tiến';
            app.ChongiatrisaisLabel = uilabel(app.DaoHam_Tab);
            app.ChongiatrisaisLabel.Text = 'Chọn giá trị sai số';
            app.SaiSo_DropDown = uidropdown(app.DaoHam_Tab);
            app.SaiSo_DropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaiSo_DropDown.Value = 'O(h)';
            app.KtquaaohamchinhxacCheckBox = uicheckbox(app.DaoHam_Tab);
            app.KtquaaohamchinhxacCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DaoHam, true);
            app.KtquaaohamchinhxacCheckBox.Text = 'Kết quả đạo hàm chính xác:';
            app.TichPhan_Tab = uitab(app.TabGroup);
            app.TichPhan_Tab.Title = 'Tích phân';
            app.UIAxes_3 = uiaxes(app.TichPhan_Tab);
            title(app.UIAxes_3, 'Đồ thị tích phân')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.X_EditField_2 = uieditfield(app.TichPhan_Tab, 'text');
            app.X_EditField_2.Value = '1 2 3 4 5';
            app.Y_EditField_2 = uieditfield(app.TichPhan_Tab, 'text');
            app.Y_EditField_2.Value = '1 1/2 1/3 1/4 1/5';
            app.hamso_EditField = uieditfield(app.TichPhan_Tab, 'text');
            app.hamso_EditField.Value = 'x.^3 + 2*x - 15';
            app.can_EditField = uieditfield(app.TichPhan_Tab, 'text');
            app.can_EditField.Value = '1 5';
            app.kq_xyEditField_tp = uieditfield(app.TichPhan_Tab, 'text');
            app.kq_fxEditField_tp = uieditfield(app.TichPhan_Tab, 'text');
            app.RUNButton_tp = uibutton(app.TichPhan_Tab, 'push');
            app.RUNButton_tp.ButtonPushedFcn = createCallbackFcn(app, @Run_TichPhan, true);
            app.RUNButton_tp.Text = 'RUN';
            app.X_Label = uilabel(app.TichPhan_Tab);
            app.X_Label.Text = 'Nhập dữ liệu x';
            app.Y_Label = uilabel(app.TichPhan_Tab);
            app.Y_Label.Text = 'Nhập dữ liệu y';
            app.Can_Label = uilabel(app.TichPhan_Tab);
            app.Can_Label.Text = 'Nhập cận tích phân';
            app.HamLabel = uilabel(app.TichPhan_Tab);
            app.HamLabel.Text = 'Nhập hàm số f(x)';
            app.Kq_xyLabel = uilabel(app.TichPhan_Tab);
            app.Kq_xyLabel.Text = 'Kết quả tích phân của dữ liệu x, y';
            app.Kq_fxLabel = uilabel(app.TichPhan_Tab);
            app.Kq_fxLabel.Text = 'Kết quả tích phân của hàm f(x)';
            app.Kq_CXacCheckBox = uicheckbox(app.TichPhan_Tab);
            app.Kq_CXacCheckBox.ValueChangedFcn = createCallbackFcn(app, @Kq_CXacCheckBoxValueChanged, true);
            app.Kq_CXacCheckBox.Text = 'Kết quả tích phân chính xác của hàm f(x): ';
            app.Y_Label_tp = uilabel(app.TichPhan_Tab);
            app.Y_Label_tp.Text = 'Nhập số lần lặp N';
            app.N = uieditfield(app.TichPhan_Tab, 'text');
            app.N.Value = '5';
            app.ChonphngphapTichPhnDropDownLabel = uilabel(app.TichPhan_Tab);
            app.ChonphngphapTichPhnDropDownLabel.Text = 'Chọn phương pháp Tích Phân ';
            app.PPtp_DropDown = uidropdown(app.TichPhan_Tab);
            app.PPtp_DropDown.Items = {'Hình Thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PPtp_DropDown.Value = 'Hình Thang';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_MatlabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end